classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EditHoughSensitivity     matlab.ui.control.NumericEditField
        GotoLabel_18             matlab.ui.control.Label
        ButtonExportXY           matlab.ui.control.Button
        ButtonTrackFilaments     matlab.ui.control.Button
        GotoLabel_16             matlab.ui.control.Label
        EditHorzTrackPosition    matlab.ui.control.NumericEditField
        GotoLabel_15             matlab.ui.control.Label
        EditVertTrackPosition    matlab.ui.control.NumericEditField
        GotoLabel_14             matlab.ui.control.Label
        GotoLabel_13             matlab.ui.control.Label
        ButtonCrop               matlab.ui.control.Button
        ButtonStrip              matlab.ui.control.Button
        CheckBoxShowTracked      matlab.ui.control.CheckBox
        EditPixelsPerMillimeter  matlab.ui.control.NumericEditField
        GotoLabel_12             matlab.ui.control.Label
        EditFilamentDiameter     matlab.ui.control.NumericEditField
        GotoLabel_11             matlab.ui.control.Label
        EditBrushWidth           matlab.ui.control.NumericEditField
        GotoLabel_10             matlab.ui.control.Label
        EditBrushBodyDiameter    matlab.ui.control.NumericEditField
        GotoLabel_17             matlab.ui.control.Label
        EditBrushDiameter        matlab.ui.control.NumericEditField
        GotoLabel_9              matlab.ui.control.Label
        Status                   matlab.ui.control.EditField
        StatusLabel              matlab.ui.control.Label
        RadioGroupDisplayMode    matlab.ui.container.ButtonGroup
        RadioBinary              matlab.ui.control.RadioButton
        RadioGray                matlab.ui.control.RadioButton
        RadioColor               matlab.ui.control.RadioButton
        EditFrameStep            matlab.ui.control.NumericEditField
        GotoLabel_8              matlab.ui.control.Label
        EditGrayThresh           matlab.ui.control.NumericEditField
        GotoLabel_7              matlab.ui.control.Label
        EditTrackWindowHeight    matlab.ui.control.NumericEditField
        GotoLabel_6              matlab.ui.control.Label
        ButtonLoad               matlab.ui.control.Button
        ButtonSave               matlab.ui.control.Button
        EditCropLower            matlab.ui.control.NumericEditField
        GotoLabel_5              matlab.ui.control.Label
        EditCropUpper            matlab.ui.control.NumericEditField
        GotoLabel_4              matlab.ui.control.Label
        EditCropRight            matlab.ui.control.NumericEditField
        GotoLabel_3              matlab.ui.control.Label
        EditCropLeft             matlab.ui.control.NumericEditField
        GotoLabel_2              matlab.ui.control.Label
        ButtonSetLast            matlab.ui.control.Button
        ButtonSetFirst           matlab.ui.control.Button
        ButtonPause              matlab.ui.control.Button
        ButtonPlay               matlab.ui.control.Button
        EditCurrentFrame         matlab.ui.control.NumericEditField
        GotoLabel                matlab.ui.control.Label
        ButtonFrameBackward      matlab.ui.control.Button
        ButtonFrameForward       matlab.ui.control.Button
        SliderCurrentFrame       matlab.ui.control.Slider
        EditTotalFramesRatio     matlab.ui.control.NumericEditField
        EditImportStep           matlab.ui.control.NumericEditField
        CheckBoxResetCropEdits   matlab.ui.control.CheckBox
        ButtonImportVideo        matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        inPath = 'indata'
        videoFileName
        stream
        height
        width
        
        currentFrame = 1
        numFrames = 1
        frameStep = 1        
        firstFrame = 1
        lastFrame = Inf
        playing = false

        %radiusExpect        

        imageRGB
        imageGray
        imageBin
        Centroid

        imageBlob
        houghCenters
        houghRadii
        houghMetric
        
    end
    
    methods (Access = private)

        function DisableAllElements(app)
            app.playing = false;
            app.ButtonImportVideo.Enable = "off";
            app.SliderCurrentFrame.Enable = "off";
            app.ButtonFrameForward.Enable = "off";
            app.ButtonFrameBackward.Enable = "off";
            app.EditCurrentFrame.Enable = "off";
            app.ButtonPlay.Enable = "off";
            app.ButtonPause.Enable = "off";
            app.ButtonSetFirst.Enable = "off";
            app.ButtonSetLast.Enable = "off";
            app.EditCropLeft.Enable = "off";
            app.EditCropRight.Enable = "off";
            app.EditCropUpper.Enable = "off";
            app.EditCropLower.Enable = "off";
            app.ButtonSave.Enable = "off";            
            app.EditTrackWindowHeight.Enable = "off";
            app.EditGrayThresh.Enable = "off";
            app.EditFrameStep.Enable = "off";
            app.RadioGroupDisplayMode.Enable = "off";
            app.RadioBinary.Enable = "off";
            app.RadioGray.Enable = "off";
            app.RadioColor.Enable = "off";
            app.ButtonStrip.Enable = "off";
            app.ButtonCrop.Enable = "off";
            app.EditVertTrackPosition.Enable = "off";
            app.EditHorzTrackPosition.Enable = "off";
            app.EditHoughSensitivity.Enable = "off";
            app.ButtonTrackFilaments.Enable = "off";
            app.ButtonExportXY.Enable = "off";
        end

        function EnableAfterImport(app)
            app.ButtonImportVideo.Enable = "on";
            app.SliderCurrentFrame.Enable = "on";
            app.ButtonFrameForward.Enable = "on";
            app.ButtonFrameBackward.Enable = "on";
            app.EditCurrentFrame.Enable = "on";
            app.ButtonPlay.Enable = "on";
            app.ButtonPause.Enable = "on";
            app.ButtonSetFirst.Enable = "on";
            app.ButtonSetLast.Enable = "on";
            app.EditCropLeft.Enable = "on";
            app.EditCropRight.Enable = "on";
            app.EditCropUpper.Enable = "on";
            app.EditCropLower.Enable = "on";
            %app.ButtonSave.Enable = "on";            
            app.EditTrackWindowHeight.Enable = "on";
            app.EditGrayThresh.Enable = "on";
            app.EditFrameStep.Enable = "on";
            app.RadioGroupDisplayMode.Enable = "on";
            app.RadioBinary.Enable = "on";
            app.RadioGray.Enable = "on";
            app.RadioColor.Enable = "on";
            app.ButtonStrip.Enable = "on";
            app.ButtonCrop.Enable = "on";
            app.EditVertTrackPosition.Enable = "on";
            app.EditHorzTrackPosition.Enable = "on";
            app.EditHoughSensitivity.Enable = "on";
            app.ButtonTrackFilaments.Enable = "on";
        end

        % Go to frame at index, but clamp first
        function GotoFrame(app, index)
            app.currentFrame = Clamp(app, index, 1, app.numFrames);
            app.EditCurrentFrame.Value = app.currentFrame;
            app.SliderCurrentFrame.Value = app.currentFrame;
            Refresh(app);
        end
        
        % Update current image in UIAxes
        function Refresh(app)


            if app.RadioColor.Value
                image = app.imageRGB{app.currentFrame};
            elseif app.RadioGray.Value
                image = app.imageGray{app.currentFrame};      
            elseif app.RadioBinary.Value
                image = app.imageBin{app.currentFrame};
            end
%             if app.CheckBoxShowTracked.Value && ~isempty(app.houghCenters)
%                 viscircles(app.UIAxes, app.houghCenters{app.currentFrame}, app.houghRadii{app.currentFrame},'Color','r');
%             end
            if app.CheckBoxShowTracked.Value && ~isempty(app.Centroid)
                image = insertMarker(image, app.Centroid{app.currentFrame}, '+', 'Color', 'red');
            end
            imshow(image, 'Parent', app.UIAxes);
            hold(app.UIAxes, "on")

            ShowSupportLines(app);
            
            hold(app.UIAxes, "off")
            %set(app.UIAxes, 'Position', [1 1 app.height app.width]);
            drawnow;
        end

        
        % Draw lines that mark the cropping and tracking areas
        function ShowSupportLines(app)
            % Cropping area
            plot(app.UIAxes, [1 app.width], [app.EditCropUpper.Value app.EditCropUpper.Value],'Color','g','LineWidth',1);
            plot(app.UIAxes, [1 app.width], [app.EditCropLower.Value app.EditCropLower.Value],'Color','g','LineWidth',1);
            plot(app.UIAxes, [app.EditCropLeft.Value app.EditCropLeft.Value], [1 app.height] ,'Color','g','LineWidth',1);
            plot(app.UIAxes, [app.EditCropRight.Value app.EditCropRight.Value], [1 app.height] ,'Color','g','LineWidth',1);

            % Tracking area
            plot(app.UIAxes, [1 app.width], [app.EditVertTrackPosition.Value app.EditVertTrackPosition.Value],'Color','b','LineWidth',1);
            plot(app.UIAxes, [app.EditHorzTrackPosition.Value app.EditHorzTrackPosition.Value], [1 app.height] ,'Color','b','LineWidth',1);
            
        end

        % Set height and width of images
        function SetFrameSize(app, height, width)            
            app.height = height;
            app.width = width;
        end

        % Clamp value to interval a and b
        function val = Clamp(~, val, a, b)
            if val < a
                val = 1;
            elseif val > b
                val = b;
            end
        end

        function SetNumFrames(app, index)
            app.numFrames = index;
        end
        
        function SetFirstFrame(app, index)
            app.firstFrame = index;
        end

        function SetLastFrame(app, index)
            app.lastFrame = index;
        end

        function SetSliderLimits(app, first, last)
            app.SliderCurrentFrame.Limits(1) = Clamp(app, first, 1, app.numFrames);
            app.SliderCurrentFrame.Limits(2) = Clamp(app, last, 1, app.numFrames);
        end

        function UpdateTrackPositions(app)
            app.EditHorzTrackPosition.Value = round(mean([app.EditCropLeft.Value app.EditCropRight.Value]));
            app.EditVertTrackPosition.Value = round(mean([app.EditCropUpper.Value app.EditCropLower.Value]));
            app.EditPixelsPerMillimeter.Value = (app.EditCropRight.Value - app.EditCropLeft.Value) / app.EditBrushWidth.Value;            
        end

        function MakeGray(app, force)
            if (isempty(app.imageGray) || force) && ~isempty(app.imageRGB) 
                app.imageGray = cell(1, app.stream.NumFrames);
                wb = waitbar(0, 'Grayscaling images');
                n = length(app.imageRGB);
                for i = 1:n
                    app.imageGray{i} = im2gray(im2single(app.imageRGB{i}));
                    waitbar(i / n, wb);
                end
                close(wb);
            end
        end

        function MakeBinary(app, force)
            MakeGray(app, false);
            if (isempty(app.imageBin) || force) && ~isempty(app.imageGray)                
                app.imageBin = cell(1, app.stream.NumFrames);
                wb = waitbar(0, 'Binarizing images');
                n = length(app.imageGray);
                for i = 1:n
                    app.imageBin{i} = imbinarize(app.imageGray{i});
                    %app.imageBin{i} = imbinarize(app.imageGray{i}, app.EditGrayThresh.Value);
                    waitbar(i / n, wb);
                end
                close(wb);
            end
        end

        function images = CropImages(app, images, cropRect, message)
            if ~isempty(images)
                wb = waitbar(0, message);
                for i = 1:app.numFrames
                    images{i} = imcrop(images{i}, cropRect);
                    waitbar(i / app.numFrames, wb);
                end
                close(wb);
            end
        end

        % Srip frames before firstFrame and after lastFrame off
        function outages = StripImages(app, images, message)            
            if ~isempty(images)
                wb = waitbar(0, message);
                n = app.lastFrame - app.firstFrame + 1;
                outages = cell(1, n);
                i = 0;
                for j = app.firstFrame:app.lastFrame
                    i = i + 1;
                    outages{i} = images{j};
                    waitbar(i / n, wb);
                end
                close(wb);
            else
                outages = images;
            end
        end

        function ResetCropEdits(app)
            app.EditCropLeft.Value = 1;
            app.EditCropUpper.Value = 1;            
            app.EditCropRight.Value = app.width;
            app.EditCropLower.Value = app.height;           
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonImportVideo
        function ButtonImportVideoPushed(app, event)
            DisableAllElements(app);

            %app.videoFileName = uigetfile({'*.MOV';'*.mov';'*.AVI';'*.avi'}, 'File Selector', app.inPath);
            app.videoFileName = "150x25x1,1_RQC203.MOV";

            disp("Open file: " + app.videoFileName)

            app.stream = VideoReader(fullfile(app.inPath, app.videoFileName));
            SetFrameSize(app, app.stream.Height, app.stream.Width)
            if app.CheckBoxResetCropEdits.Value
                ResetCropEdits(app);
            end

            app.numFrames = round(app.stream.NumFrames * app.EditTotalFramesRatio.Value) - 1;
            
            if app.EditCropLower.Value == 1
                app.EditCropLower.Value = app.height;
            end
            if app.EditCropRight.Value == 1
                app.EditCropRight.Value = app.width;
            end
            UpdateTrackPositions(app);
            
            app.imageRGB = cell(1, app.stream.NumFrames);
            wb = waitbar(0, 'Importing video');

            % Read movie frames into image            
            i = 0;
            for index = 1:app.EditImportStep.Value:app.numFrames
                app.stream.CurrentTime = index / app.stream.FrameRate;
                i = i + 1;
                app.imageRGB{i} = readFrame(app.stream);
                waitbar(index / app.numFrames, wb);
            end

            SetNumFrames(app, i);
            SetFirstFrame(app, 1);
            SetLastFrame(app, app.numFrames);
            SetSliderLimits(app, 1, app.numFrames);

            close(wb)
            EnableAfterImport(app)
            Refresh(app);

            
        end

        % Value changed function: EditCurrentFrame
        function EditCurrentFrameValueChanged(app, event)
            GotoFrame(app, app.EditCurrentFrame.Value);            
        end

        % Button pushed function: ButtonFrameForward
        function ButtonFrameForwardPushed(app, event)
            GotoFrame(app, app.currentFrame + app.frameStep);
        end

        % Button pushed function: ButtonFrameBackward
        function ButtonFrameBackwardPushed(app, event)
            GotoFrame(app, app.currentFrame - app.frameStep);
        end

        % Value changed function: EditFrameStep
        function EditFrameStepValueChanged(app, event)
            app.frameStep = app.EditFrameStep.Value;            
        end

        % Button pushed function: ButtonPlay
        function ButtonPlayPushed(app, event)
            app.playing = true;
            while app.playing
                if app.currentFrame >= app.lastFrame  % after limits
                    GotoFrame(app, app.firstFrame);  % jump to start
                elseif app.currentFrame < app.firstFrame  % before limits
                    GotoFrame(app, app.firstFrame);  % jump to start
                else  % within limits
                    GotoFrame(app, app.currentFrame + app.frameStep);  % next frame
                end
            end
        end

        % Button pushed function: ButtonPause
        function ButtonPausePushed(app, event)
            app.playing = false;
        end

        % Value changed function: EditCropLeft
        function EditCropLeftValueChanged(app, event)
            UpdateTrackPositions(app);
            Refresh(app);
        end

        % Value changed function: EditCropUpper
        function EditCropUpperValueChanged(app, event)
            UpdateTrackPositions(app);
            Refresh(app);
        end

        % Value changed function: EditCropRight
        function EditCropRightValueChanged(app, event)
            UpdateTrackPositions(app);
            Refresh(app);
        end

        % Value changed function: EditCropLower
        function EditCropLowerValueChanged(app, event)
            UpdateTrackPositions(app);
            Refresh(app);
        end

        % Value changing function: SliderCurrentFrame
        function SliderCurrentFrameValueChanging(app, event)
            app.EditCurrentFrame.Value = event.Value;
            EditCurrentFrameValueChanged(app, event);
        end

        % Button pushed function: ButtonSetFirst
        function ButtonSetFirstPushed(app, event)
            SetFirstFrame(app, app.currentFrame);
        end

        % Button pushed function: ButtonSetLast
        function ButtonSetLastPushed(app, event)
            SetLastFrame(app, app.currentFrame);
        end

        % Value changed function: EditVertTrackPosition
        function EditVertTrackPositionValueChanged(app, event)
            Refresh(app);
        end

        % Value changed function: EditHorzTrackPosition
        function EditHorzTrackPositionValueChanged(app, event)
            Refresh(app);
        end

        % Selection changed function: RadioGroupDisplayMode
        function RadioGroupDisplayModeSelectionChanged(app, event)
            %selectedButton = app.RadioGroupDisplayMode.SelectedObject;
            if app.RadioGray.Value
                MakeGray(app, false);
            elseif app.RadioBinary.Value
                MakeBinary(app, false);
            end
            Refresh(app);
        end

        % Value changed function: EditGrayThresh
        function EditGrayThreshValueChanged(app, event)
            MakeBinary(app, true);
            Refresh(app);
        end

        % Button pushed function: ButtonCrop
        function ButtonCropPushed(app, event)
            app.width = app.EditCropRight.Value - app.EditCropLeft.Value;
            app.height = app.EditCropLower.Value - app.EditCropUpper.Value;
            cropRect = [app.EditCropLeft.Value, app.EditCropUpper.Value, app.width, app.height];
            app.imageRGB = CropImages(app, app.imageRGB, cropRect, 'Cropping Color Images');
            app.imageGray = CropImages(app, app.imageGray, cropRect, 'Cropping Grayscale Images');
            app.imageBin = CropImages(app, app.imageBin, cropRect, 'Cropping Binarized Images');
            ResetCropEdits(app);
            UpdateTrackPositions(app);
            Refresh(app);
        end

        % Button pushed function: ButtonStrip
        function ButtonStripPushed(app, event)
            app.imageRGB = StripImages(app, app.imageRGB, 'Stripping Color Images');
            app.imageGray = StripImages(app, app.imageGray, 'Stripping Grayscale Images');
            app.imageBin = StripImages(app, app.imageBin, 'Stripping Binarized Images');
            SetNumFrames(app, length(app.imageRGB));
            SetFirstFrame(app, 1);
            SetLastFrame(app, app.numFrames);
            SetSliderLimits(app, 1, app.numFrames);
            GotoFrame(app, 1);
        end

        % Button pushed function: ButtonTrackFilaments
        function ButtonTrackFilamentsPushed(app, event)


            MakeBinary(app, true);
%             Refresh(app);
%             return
% 
%             MakeGray(app, false);
%             MakeBinary(app, false);
            radiusExpect = round([0.3 0.7] * app.EditPixelsPerMillimeter.Value * app.EditFilamentDiameter.Value);

            wb = waitbar(0, "Tracking filaments");
%             app.houghCenters = cell(1, app.numFrames);
%             app.houghRadii = cell(1, app.numFrames);
%             app.houghMetric = cell(1, app.numFrames);
            
            yRefPos = round(app.EditVertTrackPosition.Value - app.EditTrackWindowHeight.Value / 2);
            houghRect = [1 yRefPos app.width app.EditTrackWindowHeight.Value];

            hblob = vision.BlobAnalysis( ...
                'AreaOutputPort', false, ...
                'BoundingBoxOutputPort', false, ...
                'OutputDataType', 'double', ...
                'MinimumBlobArea', round(pi * radiusExpect(1)^2), ...
                'MaximumBlobArea', round(pi * radiusExpect(2)^2), ...
                'MaximumCount', 1000);

            % Acknowledgement
            ackText = ['Data set courtesy of Jonathan Young and Michael Elowitz, ' ...
                         'California Institute of Technology'];
            
            app.imageBlob = cell(1, app.stream.NumFrames);
            for i = 1:app.numFrames
%                 houghImage = imcrop(app.imageBin{i}, houghRect);
%                 [app.houghCenters{i}, app.houghRadii{i}, app.houghMetric{i}] = imfindcircles(houghImage, ...
%                     radiusExpect, 'Sensitivity', app.EditHoughSensitivity.Value, 'ObjectPolarity', 'bright', 'Method', 'TwoStage');
                waitbar(i / app.numFrames, wb);
%                 app.houghCenters{i}(:,2) = app.houghCenters{i}(:,2) + yRefPos;  % move down y

%                 image = imcrop(app.imageGray{i}, houghRect);
                image = app.imageBin{i};
%                 image = imerode(image, strel('disk',4));
%                 image = imdilate(image, strel('disk',1));

                app.imageBin{i} = 255*uint8(image);%insertMarker(image, Centroid, 'o', 'Color', 'red');

%                 app.Centroid{i} = step(hblob, image);
                [app.Centroid{i}, ~, ~] = imfindcircles(image, ...
                    radiusExpect, 'Sensitivity', app.EditHoughSensitivity.Value, 'ObjectPolarity', 'bright', 'Method', 'TwoStage');

%                 y1 = 2*image - imdilate(image, strel('square',7));
%                 y1(y1<0) = 0;
%                 y1(y1>1) = 1;
%                 y2 = imdilate(y1, strel('square',7)) - y1;
%             
%                 th = multithresh(y2);      % Determine threshold using Otsu's method
%                 y3 = (y2 <= th*0.9);       % Binarize the image.
%                 
%                 Centroid = step(hblob, y3);   % Calculate the centroid
%                 numBlobs = size(Centroid,1);  % and number of cells.
%                 Display the number of frames and cells.
%                 frameBlobTxt = sprintf('Frame %d, Count %d', i, numBlobs);
%                 image = insertText(image, [1 1], frameBlobTxt, ...
%                         'FontSize', 16, 'BoxOpacity', 0, 'TextColor', 'white');
%                 image = insertText(image, [1 size(image,1)], ackText, ...
%                         'FontSize', 10, 'AnchorPoint', 'LeftBottom', ...
%                         'BoxOpacity', 0, 'TextColor', 'white');
%             
%                 % Display video
%                 app.imageBin{i} = insertMarker(uint8(y)*255, Centroid, 'o', 'Color', 'red');

            end
            close(wb);
            Refresh(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            colormap(app.UIAxes, 'gray')
            app.ButtonImportVideo = uibutton(app.UIFigure, 'push');
            app.ButtonImportVideo.ButtonPushedFcn = createCallbackFcn(app, @ButtonImportVideoPushed, true);
            app.ButtonImportVideo.Text = 'Import Video';
            app.CheckBoxResetCropEdits = uicheckbox(app.UIFigure);
            app.CheckBoxResetCropEdits.Text = 'Reset Crop Limits After Import';
            app.EditImportStep = uieditfield(app.UIFigure, 'numeric');
            app.EditImportStep.Limits = [1 Inf];
            app.EditImportStep.RoundFractionalValues = 'on';
            app.EditImportStep.Value = 1;
            app.EditTotalFramesRatio = uieditfield(app.UIFigure, 'numeric');
            app.EditTotalFramesRatio.Limits = [0.005 1];
            app.EditTotalFramesRatio.Tooltip = {'0.01: import only first percent of frames'; '1.0: import all frames'};
            app.EditTotalFramesRatio.Value = 0.01;
            app.SliderCurrentFrame = uislider(app.UIFigure);
            app.SliderCurrentFrame.MajorTicks = [];
            app.SliderCurrentFrame.MajorTickLabels = {''};
            app.SliderCurrentFrame.ValueChangingFcn = createCallbackFcn(app, @SliderCurrentFrameValueChanging, true);
            app.SliderCurrentFrame.MinorTicks = [];
            app.SliderCurrentFrame.Enable = 'off';
            app.ButtonFrameForward = uibutton(app.UIFigure, 'push');
            app.ButtonFrameForward.ButtonPushedFcn = createCallbackFcn(app, @ButtonFrameForwardPushed, true);
            app.ButtonFrameForward.Enable = 'off';
            app.ButtonFrameForward.Text = '+';
            app.ButtonFrameBackward = uibutton(app.UIFigure, 'push');
            app.ButtonFrameBackward.ButtonPushedFcn = createCallbackFcn(app, @ButtonFrameBackwardPushed, true);
            app.ButtonFrameBackward.Enable = 'off';
            app.ButtonFrameBackward.Text = '−';
            app.GotoLabel = uilabel(app.UIFigure);
            app.GotoLabel.Text = {'Current'; 'Frame'};
            app.EditCurrentFrame = uieditfield(app.UIFigure, 'numeric');
            app.EditCurrentFrame.Limits = [1 Inf];
            app.EditCurrentFrame.RoundFractionalValues = 'on';
            app.EditCurrentFrame.ValueChangedFcn = createCallbackFcn(app, @EditCurrentFrameValueChanged, true);
            app.EditCurrentFrame.Enable = 'off';
            app.EditCurrentFrame.Value = 1;
            app.ButtonPlay = uibutton(app.UIFigure, 'push');
            app.ButtonPlay.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlayPushed, true);
            app.ButtonPlay.Enable = 'off';
            app.ButtonPlay.Text = 'Play';
            app.ButtonPause = uibutton(app.UIFigure, 'push');
            app.ButtonPause.ButtonPushedFcn = createCallbackFcn(app, @ButtonPausePushed, true);
            app.ButtonPause.Enable = 'off';
            app.ButtonPause.Text = 'Pause';
            app.ButtonSetFirst = uibutton(app.UIFigure, 'push');
            app.ButtonSetFirst.ButtonPushedFcn = createCallbackFcn(app, @ButtonSetFirstPushed, true);
            app.ButtonSetFirst.Enable = 'off';
            app.ButtonSetFirst.Text = 'Set First Frame';
            app.ButtonSetLast = uibutton(app.UIFigure, 'push');
            app.ButtonSetLast.ButtonPushedFcn = createCallbackFcn(app, @ButtonSetLastPushed, true);
            app.ButtonSetLast.Enable = 'off';
            app.ButtonSetLast.Text = 'Set Last Frame';
            app.GotoLabel_2 = uilabel(app.UIFigure);
            app.GotoLabel_2.Text = 'Left';
            app.EditCropLeft = uieditfield(app.UIFigure, 'numeric');
            app.EditCropLeft.Limits = [1 Inf];
            app.EditCropLeft.RoundFractionalValues = 'on';
            app.EditCropLeft.ValueChangedFcn = createCallbackFcn(app, @EditCropLeftValueChanged, true);
            app.EditCropLeft.Enable = 'off';
            app.EditCropLeft.Value = 406;
            app.GotoLabel_3 = uilabel(app.UIFigure);
            app.GotoLabel_3.Text = 'Right';
            app.EditCropRight = uieditfield(app.UIFigure, 'numeric');
            app.EditCropRight.Limits = [1 Inf];
            app.EditCropRight.RoundFractionalValues = 'on';
            app.EditCropRight.ValueChangedFcn = createCallbackFcn(app, @EditCropRightValueChanged, true);
            app.EditCropRight.Enable = 'off';
            app.EditCropRight.Value = 714;
            app.GotoLabel_4 = uilabel(app.UIFigure);
            app.GotoLabel_4.Text = 'Upper';
            app.EditCropUpper = uieditfield(app.UIFigure, 'numeric');
            app.EditCropUpper.Limits = [1 Inf];
            app.EditCropUpper.RoundFractionalValues = 'on';
            app.EditCropUpper.ValueChangedFcn = createCallbackFcn(app, @EditCropUpperValueChanged, true);
            app.EditCropUpper.Enable = 'off';
            app.EditCropUpper.Value = 600;
            app.GotoLabel_5 = uilabel(app.UIFigure);
            app.GotoLabel_5.Text = 'Lower';
            app.EditCropLower = uieditfield(app.UIFigure, 'numeric');
            app.EditCropLower.Limits = [1 Inf];
            app.EditCropLower.RoundFractionalValues = 'on';
            app.EditCropLower.ValueChangedFcn = createCallbackFcn(app, @EditCropLowerValueChanged, true);
            app.EditCropLower.Enable = 'off';
            app.EditCropLower.Value = 1320;
            app.ButtonSave = uibutton(app.UIFigure, 'push');
            app.ButtonSave.Enable = 'off';
            app.ButtonSave.Text = 'Save Session';
            app.ButtonLoad = uibutton(app.UIFigure, 'push');
            app.ButtonLoad.Enable = 'off';
            app.ButtonLoad.Text = 'Load Session';
            app.GotoLabel_6 = uilabel(app.UIFigure);
            app.GotoLabel_6.Text = {'Tracking Window'; 'Height [px]'};
            app.EditTrackWindowHeight = uieditfield(app.UIFigure, 'numeric');
            app.EditTrackWindowHeight.RoundFractionalValues = 'on';
            app.EditTrackWindowHeight.Enable = 'off';
            app.EditTrackWindowHeight.Value = 100;
            app.GotoLabel_7 = uilabel(app.UIFigure);
            app.GotoLabel_7.Text = {'Gray'; 'Threshold'};
            app.EditGrayThresh = uieditfield(app.UIFigure, 'numeric');
            app.EditGrayThresh.Limits = [0 1];
            app.EditGrayThresh.ValueChangedFcn = createCallbackFcn(app, @EditGrayThreshValueChanged, true);
            app.EditGrayThresh.Enable = 'off';
            app.EditGrayThresh.Value = 0.5;
            app.GotoLabel_8 = uilabel(app.UIFigure);
            app.GotoLabel_8.Text = {'Frame'; 'Step'};
            app.EditFrameStep = uieditfield(app.UIFigure, 'numeric');
            app.EditFrameStep.Limits = [1 Inf];
            app.EditFrameStep.RoundFractionalValues = 'on';
            app.EditFrameStep.ValueChangedFcn = createCallbackFcn(app, @EditFrameStepValueChanged, true);
            app.EditFrameStep.Enable = 'off';
            app.EditFrameStep.Value = 1;
            app.RadioGroupDisplayMode = uibuttongroup(app.UIFigure);
            app.RadioGroupDisplayMode.SelectionChangedFcn = createCallbackFcn(app, @RadioGroupDisplayModeSelectionChanged, true);
            app.RadioGroupDisplayMode.Enable = 'off';
            app.RadioGroupDisplayMode.Title = 'Display Mode';
            app.RadioColor = uiradiobutton(app.RadioGroupDisplayMode);
            app.RadioColor.Enable = 'off';
            app.RadioColor.Text = 'Color';
            app.RadioColor.Value = true;
            app.RadioGray = uiradiobutton(app.RadioGroupDisplayMode);
            app.RadioGray.Enable = 'off';
            app.RadioGray.Text = 'Grayscale';
            app.RadioBinary = uiradiobutton(app.RadioGroupDisplayMode);
            app.RadioBinary.Enable = 'off';
            app.RadioBinary.Text = 'Binary';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.Status = uieditfield(app.UIFigure, 'text');
            app.Status.Editable = 'off';
            app.GotoLabel_9 = uilabel(app.UIFigure);
            app.GotoLabel_9.Text = {'Outer Brush'; 'Diameter [mm]'};
            app.EditBrushDiameter = uieditfield(app.UIFigure, 'numeric');
            app.EditBrushDiameter.Value = 150;
            app.GotoLabel_17 = uilabel(app.UIFigure);
            app.GotoLabel_17.Text = {'Brush Body'; 'Diameter [mm]'};
            app.EditBrushBodyDiameter = uieditfield(app.UIFigure, 'numeric');
            app.EditBrushBodyDiameter.Value = 100;
            app.GotoLabel_10 = uilabel(app.UIFigure);
            app.GotoLabel_10.Text = {'Brush'; 'Width [mm]'};
            app.EditBrushWidth = uieditfield(app.UIFigure, 'numeric');
            app.EditBrushWidth.Value = 22;
            app.GotoLabel_11 = uilabel(app.UIFigure);
            app.GotoLabel_11.Text = {'Filament'; 'Diameter [mm]'};
            app.EditFilamentDiameter = uieditfield(app.UIFigure, 'numeric');
            app.EditFilamentDiameter.Value = 1.143;
            app.GotoLabel_12 = uilabel(app.UIFigure);
            app.GotoLabel_12.Text = {'Pixels Per'; 'Millimeter'};
            app.EditPixelsPerMillimeter = uieditfield(app.UIFigure, 'numeric');
            app.EditPixelsPerMillimeter.Editable = 'off';
            app.CheckBoxShowTracked = uicheckbox(app.UIFigure);
            app.CheckBoxShowTracked.Text = 'Show Tracked Filaments';
            app.CheckBoxShowTracked.Value = true;
            app.ButtonStrip = uibutton(app.UIFigure, 'push');
            app.ButtonStrip.ButtonPushedFcn = createCallbackFcn(app, @ButtonStripPushed, true);
            app.ButtonStrip.Enable = 'off';
            app.ButtonStrip.Text = 'Strip';
            app.ButtonCrop = uibutton(app.UIFigure, 'push');
            app.ButtonCrop.ButtonPushedFcn = createCallbackFcn(app, @ButtonCropPushed, true);
            app.ButtonCrop.Enable = 'off';
            app.ButtonCrop.Text = 'Crop';
            app.GotoLabel_13 = uilabel(app.UIFigure);
            app.GotoLabel_13.Text = {'Import'; 'Step'};
            app.GotoLabel_14 = uilabel(app.UIFigure);
            app.GotoLabel_14.Text = {'Vertical Tracking'; 'Position [px]'};
            app.EditVertTrackPosition = uieditfield(app.UIFigure, 'numeric');
            app.EditVertTrackPosition.Limits = [1 Inf];
            app.EditVertTrackPosition.RoundFractionalValues = 'on';
            app.EditVertTrackPosition.ValueChangedFcn = createCallbackFcn(app, @EditVertTrackPositionValueChanged, true);
            app.EditVertTrackPosition.Enable = 'off';
            app.EditVertTrackPosition.Value = 1;
            app.GotoLabel_15 = uilabel(app.UIFigure);
            app.GotoLabel_15.Text = {'Horizontal Tracking'; 'Position [px]'};
            app.EditHorzTrackPosition = uieditfield(app.UIFigure, 'numeric');
            app.EditHorzTrackPosition.Limits = [1 Inf];
            app.EditHorzTrackPosition.RoundFractionalValues = 'on';
            app.EditHorzTrackPosition.ValueChangedFcn = createCallbackFcn(app, @EditHorzTrackPositionValueChanged, true);
            app.EditHorzTrackPosition.Enable = 'off';
            app.EditHorzTrackPosition.Value = 1;
            app.GotoLabel_16 = uilabel(app.UIFigure);
            app.GotoLabel_16.Text = {'Total Frames'; 'Ratio'};
            app.ButtonTrackFilaments = uibutton(app.UIFigure, 'push');
            app.ButtonTrackFilaments.ButtonPushedFcn = createCallbackFcn(app, @ButtonTrackFilamentsPushed, true);
            app.ButtonTrackFilaments.Text = 'Track Filaments';
            app.ButtonExportXY = uibutton(app.UIFigure, 'push');
            app.ButtonExportXY.Text = 'Export XY';
            app.GotoLabel_18 = uilabel(app.UIFigure);
            app.GotoLabel_18.Text = {'Hough'; 'Sensitivity'};
            app.EditHoughSensitivity = uieditfield(app.UIFigure, 'numeric');
            app.EditHoughSensitivity.Limits = [0 1];
            app.EditHoughSensitivity.Enable = 'off';
            app.EditHoughSensitivity.Value = 0.85;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end